classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DmmerungEditField               matlab.ui.control.NumericEditField
        DmmerungEditFieldLabel          matlab.ui.control.Label
        tglicheSonnenstundenistEditField  matlab.ui.control.NumericEditField
        tglicheSonnenstundenistEditFieldLabel  matlab.ui.control.Label
        SpeichernundbernehmenButton     matlab.ui.control.StateButton
        obererGrenzwertistEditField     matlab.ui.control.NumericEditField
        obererGrenzwertistEditFieldLabel  matlab.ui.control.Label
        untererGrenzwertistEditField    matlab.ui.control.NumericEditField
        untererGrenzwertistEditFieldLabel  matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        ZeitdauerBewaesserungEditField  matlab.ui.control.NumericEditField
        ZeitdauerBewaesserungEditFieldLabel  matlab.ui.control.Label
        BehlterhoeheEditField           matlab.ui.control.NumericEditField
        BehlterhoeheEditFieldLabel      matlab.ui.control.Label
        DmmerungSlider                  matlab.ui.control.Slider
        DmmerungSliderLabel             matlab.ui.control.Label
        tglicheSonnenstundenSlider      matlab.ui.control.Slider
        tglicheSonnenstundenLabel       matlab.ui.control.Label
        SonneLichtLabel                 matlab.ui.control.Label
        BewsserungLabel                 matlab.ui.control.Label
        obererGrenzwertSlider           matlab.ui.control.Slider
        obererGrenzwertLabel            matlab.ui.control.Label
        FeuchtigkeitLabel               matlab.ui.control.Label
        untererGrenzwertSlider          matlab.ui.control.Slider
        untererGrenzwertLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeichernundbernehmenButton
        function SpeichernundbernehmenButtonValueChanged(app, event)
            % Variablen definieren
            %humidity_lower_limit = 0;
            %humidity_upper_limit = 0;
            %reservoir_height = 0;
            %light_limit = 0;
            %time_limit_h = 0;
            %water_time = 0;
            
            % Script aufrufen
            projekt_infm_1;
            
        end

        % Value changing function: untererGrenzwertSlider
        function untererGrenzwertSliderValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.untererGrenzwertistEditField.Value=changingValue;
            
        end

        % Value changing function: obererGrenzwertSlider
        function obererGrenzwertSliderValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.obererGrenzwertistEditField.Value=changingValue;
            
        end

        % Value changing function: tglicheSonnenstundenSlider
        function tglicheSonnenstundenSliderValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.tglicheSonnenstundenistEditField.Value=changingValue;
            
        end

        % Value changing function: DmmerungSlider
        function DmmerungSliderValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.DmmerungEditField.Value=changingValue;
        end

        % Value changed function: BehlterhoeheEditField
        function BehlterhoeheEditFieldValueChanged(app, event)
            value = app.BehlterhoeheEditField.Value;
            
        end

        % Value changed function: ZeitdauerBewaesserungEditField
        function ZeitdauerBewaesserungEditFieldValueChanged(app, event)
            value = app.ZeitdauerBewaesserungEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.untererGrenzwertLabel = uilabel(app.UIFigure);
            app.untererGrenzwertLabel.Text = {'unterer '; 'Grenzwert'};
            app.untererGrenzwertSlider = uislider(app.UIFigure);
            app.untererGrenzwertSlider.ValueChangingFcn = createCallbackFcn(app, @untererGrenzwertSliderValueChanging, true);
            app.FeuchtigkeitLabel = uilabel(app.UIFigure);
            app.FeuchtigkeitLabel.Text = 'Feuchtigkeit [%]';
            app.obererGrenzwertLabel = uilabel(app.UIFigure);
            app.obererGrenzwertLabel.Text = {'oberer '; 'Grenzwert'};
            app.obererGrenzwertSlider = uislider(app.UIFigure);
            app.obererGrenzwertSlider.ValueChangingFcn = createCallbackFcn(app, @obererGrenzwertSliderValueChanging, true);
            app.BewsserungLabel = uilabel(app.UIFigure);
            app.BewsserungLabel.Text = 'Bewässerung';
            app.SonneLichtLabel = uilabel(app.UIFigure);
            app.SonneLichtLabel.Text = 'Sonne / Licht';
            app.tglicheSonnenstundenLabel = uilabel(app.UIFigure);
            app.tglicheSonnenstundenLabel.Text = {'tägliche '; 'Sonnenstunden'};
            app.tglicheSonnenstundenSlider = uislider(app.UIFigure);
            app.tglicheSonnenstundenSlider.Limits = [0 24];
            app.tglicheSonnenstundenSlider.ValueChangingFcn = createCallbackFcn(app, @tglicheSonnenstundenSliderValueChanging, true);
            app.DmmerungSliderLabel = uilabel(app.UIFigure);
            app.DmmerungSliderLabel.Text = 'Dämmerung';
            app.DmmerungSlider = uislider(app.UIFigure);
            app.DmmerungSlider.Limits = [0 20];
            app.DmmerungSlider.ValueChangingFcn = createCallbackFcn(app, @DmmerungSliderValueChanging, true);
            app.BehlterhoeheEditFieldLabel = uilabel(app.UIFigure);
            app.BehlterhoeheEditFieldLabel.Text = 'Behälterhoehe';
            app.BehlterhoeheEditField = uieditfield(app.UIFigure, 'numeric');
            app.BehlterhoeheEditField.ValueChangedFcn = createCallbackFcn(app, @BehlterhoeheEditFieldValueChanged, true);
            app.ZeitdauerBewaesserungEditFieldLabel = uilabel(app.UIFigure);
            app.ZeitdauerBewaesserungEditFieldLabel.Text = {'Zeitdauer'; 'Bewaesserung'};
            app.ZeitdauerBewaesserungEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZeitdauerBewaesserungEditField.ValueChangedFcn = createCallbackFcn(app, @ZeitdauerBewaesserungEditFieldValueChanged, true);
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.untererGrenzwertistEditFieldLabel = uilabel(app.UIFigure);
            app.untererGrenzwertistEditFieldLabel.Text = 'unterer Grenzwert ist';
            app.untererGrenzwertistEditField = uieditfield(app.UIFigure, 'numeric');
            app.obererGrenzwertistEditFieldLabel = uilabel(app.UIFigure);
            app.obererGrenzwertistEditFieldLabel.Text = 'oberer Grenzwert ist';
            app.obererGrenzwertistEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeichernundbernehmenButton = uibutton(app.UIFigure, 'state');
            app.SpeichernundbernehmenButton.ValueChangedFcn = createCallbackFcn(app, @SpeichernundbernehmenButtonValueChanged, true);
            app.SpeichernundbernehmenButton.Text = 'Speichern und übernehmen';
            app.tglicheSonnenstundenistEditFieldLabel = uilabel(app.UIFigure);
            app.tglicheSonnenstundenistEditFieldLabel.Text = 'tägliche Sonnenstunden ist';
            app.tglicheSonnenstundenistEditField = uieditfield(app.UIFigure, 'numeric');
            app.DmmerungEditFieldLabel = uilabel(app.UIFigure);
            app.DmmerungEditFieldLabel.Text = 'Dämmerung';
            app.DmmerungEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end